classdef Actividad3_ej < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        xaSliderLabel      matlab.ui.control.Label
        XASlider           matlab.ui.control.Slider
        xbSliderLabel      matlab.ui.control.Label
        XBSlider           matlab.ui.control.Slider
        ErrorSliderLabel   matlab.ui.control.Label
        ErrorSlider        matlab.ui.control.Slider
        SolveProblem       matlab.ui.control.Button
        valSliderLabel     matlab.ui.control.Label
        valSlider          matlab.ui.control.Slider
        maxSliderLabel     matlab.ui.control.Label
        maxSlider          matlab.ui.control.Slider
        RightPanel         matlab.ui.container.Panel
        Result             matlab.ui.control.Label
        ElectricFiledPlot  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function findRoot(app)
            % Función que actualiza el resultado y la gráfica.
            
            % Definición de la función que se va a resolver
            q1 = -5e-6;
            q2 = -2e-6;
            q3 = -3e-6;
            q4 = -4e-6;
            
            x1 = 0;
            x2 = 0.1;
            x3 = 0.2;
            x4 = 0.3;
            
            k = 1/(4*pi*8.8541878176e-12);
            
            f = @(x) k*q1.*sign(x-x1)./(x-x1).^2 + k*q2.*sign(x-x2)./(x-x2).^2 + k*q3.*sign(x-x3)./(x-x3).^2 + k*q4.*sign(x-x4)./(x-x4).^2;
            
            % Gráfica de la función
            x = linspace(app.valSlider.Value, app.maxSlider.Value, 99);

            cla(app.ElectricFiledPlot, "reset")
            plot(app.ElectricFiledPlot, x, f(x), 'r', 'linewidth', 3)
            grid(app.ElectricFiledPlot, 'on')
            xlabel(app.ElectricFiledPlot, 'x')
            ylabel(app.ElectricFiledPlot, 'y')
                        
            hold(app.ElectricFiledPlot, 'on')
            scatter(app.ElectricFiledPlot, 0, 0, 'b', 'linewidth', 3, "MarkerFaceColor",'b')
            scatter(app.ElectricFiledPlot, 0.1, 0, 'b', 'linewidth', 3, "MarkerFaceColor",'b')
            scatter(app.ElectricFiledPlot, 0.2, 0, 'b', 'linewidth', 3, "MarkerFaceColor",'b')
            scatter(app.ElectricFiledPlot, 0.3, 0, 'b', 'linewidth', 3, "MarkerFaceColor",'b')
            hold(app.ElectricFiledPlot, "off")
            
            % Solución de la ecuación                        
            xa = app.XASlider.Value;
            xb = app.XBSlider.Value;
            errmax = app.ErrorSlider.Value;
            
            try            
                [xr, fr, err] = bisection(app, f, xa, xb, errmax);            
                app.Result.Text = "xr: " + xr + "  f(xr): " + fr + "  Error: " + err;
                hold(app.ElectricFiledPlot, 'on')
                scatter(app.ElectricFiledPlot, xr, 0, 'g', 'linewidth', 3, "MarkerFaceColor",'g')
                hold(app.ElectricFiledPlot, "off")
            catch
                app.Result.Text = "El algoritmo no fue capaz de encontrar una solución";
            end
            
        end
        
        function [xr, fr, err] = bisection(~, f, xa, xb, errmax)
            % Función que resuelve una ecuación de una variable por 
            % el método de bisección.
            %
            % Entradas:
            %   f         Función que define la ecuación a resolver.
            %   xa        Valor inicial del rango de la ecuación.
            %   xb        Valor final del rango de la ecuación.
            %   errmax    Máximo error permitido para la solución.
            %
            % Salidas:
            %   xr        Raíz de la ecuación encontrada.
            %   fr        Valor de la ecuación en la raíz encontrada.
            %   err       Error porcentual de la última iteración.
            
            % Verifica que los parámetros de entrada sean válidos.
            if nargin ~= 5
                error('Cantidad de parámetros de entrada no válido.')
            end
            
            if ~isscalar(xa) || ~isnumeric(xa)
                error('El valor xa debe ser numérico y escalar.')
            end
            
            if ~isscalar(xb) || ~isnumeric(xb)
                error('El valor xb debe ser numérico y escalar.')
            end
            
            if ~isscalar(errmax) || ~isnumeric(errmax) || errmax <= 0
                error('El valor errmax debe ser numérico, escalar y mayor a cero.')
            end
            
            % Evalúa la función en los puntos iniciales.
            fa = f(xa);
            fb = f(xb);
            
            % Verifica si los valores iniciales son una solución.
            if fa == 0
                xr = xa;
                fr = 0;
                err = 0;
                return 
            end
            
            if fb == 0
                xr = xb;
                fr = 0;
                err = 0;
                return 
            end
            
            % Verifica que entre los valores dados haya una solución.
            if fa*fb > 0
                error('Valores iniciales no válidos.')
            end
            
            % Algoritmo de bisección.
            err = 100*errmax;
            xm = xa;
            while err > errmax
                
                % Valor anterior de xm
                xma = xm;
                
                % Nuevo valor de xm
                xm = (xa + xb)/2;
                fm = f(xm);
                
                % Calcula el error actual.
                err = 100*(abs(xm - xma)/max([abs(xm), eps]));
                
                % Despliega los valores actuales.
                %disp("xa: " + xa + "  xb: " + xb + "  xm: " + xm + ...
                %    "  fa: " + fa + "  fb: " + fb + "  fm: " + fm + "  error: " + err)
                
                % Actualiza los límites del intervalo.
                if fa*fm < 0
                    % En este caso, xb se mueve a la posición xm.
                    xb = xm;
                    fb = fm;
                elseif fb*fm < 0
                    % En este caso, xa se mueve a la posición xm.
                    xa = xm;
                    fa = fm;
                else
                    % En este caso tenemos una solución en xm.
                    err = 0;
                end     
                
            end
            
            % Guarda el resultado en las variables de salida.
            xr = xm;
            fr = fm;
        end
        
    end   
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XASlider
        function xaChanged(app, event)
            % Función que se invoca cuando el valor de xa cambia.
            findRoot(app)            
        end

        % Value changed function: XBSlider
        function xbChanged(app, event)
            % Función que se invoca cuando el valor de xb cambia.
            findRoot(app)
        end

        % Value changed function: ErrorSlider
        function errorChanged(app, event)
           % Función que se invoca cuando el valor del error. 
            findRoot(app)            
        end

        % Button pushed function: SolveProblem
        function solveProblem(app, event)
            % Función que se invoca cuando se presiona el botón de resolver
            % problema.
            findRoot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {221, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xaSliderLabel = uilabel(app.LeftPanel);
            app.xaSliderLabel.Text = 'xa';
            app.XASlider = uislider(app.LeftPanel);
            app.XASlider.Limits = [0 0.3];
            app.XASlider.ValueChangedFcn = createCallbackFcn(app, @xaChanged, true);
            app.XASlider.Value = 0.05;
            app.xbSliderLabel = uilabel(app.LeftPanel);
            app.xbSliderLabel.Text = 'xb';
            app.XBSlider = uislider(app.LeftPanel);
            app.XBSlider.Limits = [0 0.3];
            app.XBSlider.ValueChangedFcn = createCallbackFcn(app, @xbChanged, true);
            app.XBSlider.Value = 0.19;
            app.ErrorSliderLabel = uilabel(app.LeftPanel);
            app.ErrorSliderLabel.Text = 'Error';
            app.ErrorSlider = uislider(app.LeftPanel);
            app.ErrorSlider.Limits = [0 1];
            app.ErrorSlider.ValueChangedFcn = createCallbackFcn(app, @errorChanged, true);
            app.ErrorSlider.Value = 0.01;
            app.SolveProblem = uibutton(app.LeftPanel, 'push');
            app.SolveProblem.ButtonPushedFcn = createCallbackFcn(app, @solveProblem, true);
            app.SolveProblem.Text = 'Encontrar solución';
            app.valSliderLabel = uilabel(app.LeftPanel);
            app.valSliderLabel.Text = {'val'; ''};
            app.valSlider = uislider(app.LeftPanel);
            app.valSlider.Limits = [-1 1];
            app.maxSliderLabel = uilabel(app.LeftPanel);
            app.maxSliderLabel.Text = 'max';
            app.maxSlider = uislider(app.LeftPanel);
            app.maxSlider.Limits = [-1 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.Result = uilabel(app.RightPanel);
            app.Result.Text = '';
            app.ElectricFiledPlot = uiaxes(app.RightPanel);
            title(app.ElectricFiledPlot, 'Ejercicio 1')
            xlabel(app.ElectricFiledPlot, 'X')
            ylabel(app.ElectricFiledPlot, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Actividad3_ej

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end